classdef Integrator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IntegratorUIFigure            matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        DefIntegralEditField          matlab.ui.control.NumericEditField
        DefIntegralEditFieldLabel     matlab.ui.control.Label
        SubintervalsEditField         matlab.ui.control.NumericEditField
        SubintervalsEditFieldLabel    matlab.ui.control.Label
        RightEndpointEditField        matlab.ui.control.NumericEditField
        RightEndpointEditFieldLabel   matlab.ui.control.Label
        LeftEndpointEditField         matlab.ui.control.NumericEditField
        LeftEndpointEditFieldLabel    matlab.ui.control.Label
        RangeEditField                matlab.ui.control.NumericEditField
        RangeEditFieldLabel           matlab.ui.control.Label
        PlotIntegralButton            matlab.ui.control.Button
        PlotFunctionButton            matlab.ui.control.Button
        FunctionEditField             matlab.ui.control.EditField
        FunctionEditFieldLabel        matlab.ui.control.Label
        IntegrationTypeDropDown       matlab.ui.control.DropDown
        IntegrationTypeDropDownLabel  matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        le % left endpoint
        re % right endpoint
        N % intervals
        r % range
    end
    
    methods (Access = private)
        function I = lftapp(app,f) %Left Endpoint Approximation
            h = (app.re-app.le)/app.N;
            I = zeros(app.N,3);
            for i = 1:app.N
                I(i,1) = app.le + (i-1)*h;
            end
            I(:,2) = f(I(:,1));
            I(:,3) = h*I(:,2);
        end
        
        function I = rghtapp(app,f) %Right Endpoint Approximation
            h = (app.re-app.le)/app.N;
            I = zeros(app.N,3);
            for i = 1:app.N
                I(i,1) = app.le + i*h;
            end
            I(:,2) = f(I(:,1));
            I(:,3) = h*I(:,2);
        end
        
        function I = midapp(app,f) %Midpoint Approximation
            h = (app.re-app.le)/app.N;
            I = zeros(app.N,3);
            for i = 1:app.N
                xi = app.le + i*h;
                xj = app.le + (i-1)*h;
                I(i,1)  = (xj+xi)/2;
            end
            I(:,2) = f(I(:,1));
            I(:,3) = h*I(:,2);
        end
        
        function I = trapapp(app,f) %Trapezoidal Approximation
            h = (app.re-app.le)/app.N;
            I = zeros(app.N,2);
            for i = 0:app.N
                I(i+1,1) = app.le + i*h;
                if i == 0 
                    I(i+1,2) = 0.5*h*f(I(i+1,1));
                elseif i == app.N
                    I(i+1,2) = 0.5*h*f(I(i+1,1));
                else
                    I(i+1,2) = 0.5*h*2*f(I(i+1,1));
                end
            end
        end
        
        function I = simpapp(app,f) %Simpsons Approximation
            h = (app.re-app.le)/app.N;
            I = zeros(app.N,2);
            for i = 0:app.N
                I(i+1,1) = app.le + i*h;
                if i == 0 
                    I(i+1,2) = 0.5*h*f(I(i+1,1));
                elseif i == app.N
                    I(i+1,2) = 0.5*h*f(I(i+1,1));
                elseif rem(i,2) == 0
                    I(i+1,2) = 0.5*h*2*f(I(i+1,1));
                else
                    I(i+1,2) = 0.5*h*4*f(I(i+1,1));
                end
            end
        end
        
        function I = calcInt(app,f,type)
            if strcmp(type, 'Left Endpoint')
                I = lftapp(app,f);
            elseif strcmp(type, 'Right Endpoint')
                I = rghtapp(app,f);
            elseif strcmp(type, 'Midpoint')
                I = midapp(app,f);
            elseif strcmp(type, 'Trapezoidal')
                I = trapapp(app,f);
            elseif strcmp(type, 'Simpson')
                I = simpapp(app,f);
            else
                
                return
            end
        end
        
        function pltrec(app,I,type)
            A      = zeros(app.N,4);
            A(:,1) = I(:,1);
            for i  = 1:size(I,1)    
                if I(i,2) < 0
                    A(i,2) = I(i,2);
                end
            end
            A(:,3) = abs((app.le-app.re))/app.N;
            A(:,4) = abs(I(:,2));
            if strcmp(type, 'Left Endpoint')
                for i = 1:size(A,1)
                    rectangle(app.UIAxes,'Position',A(i,:),'FaceColor', [0 0 1 0.5],'EdgeColor','k')
                end
            elseif strcmp(type, 'Right Endpoint')
                A(:,1) = I(:,1) - A(:,3);
                for i = 1:size(A,1)
                    rectangle(app.UIAxes,'Position',A(i,:),'FaceColor', [0 0 1 0.5],'EdgeColor','k')
                end
            elseif strcmp(type, 'Midpoint')
                A(:,1) = I(:,1) - .5*A(:,3);
                for i = 1:size(A,1)
                    rectangle(app.UIAxes,'Position',A(i,:),'FaceColor', [0 0 1 0.5],'EdgeColor','k')
                end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotFunctionButton
        function PlotFunctionButtonPushed(app, event)
            cla(app.UIAxes)
            f     = app.FunctionEditField.Value;
            fh    = str2func(f);
            app.r = app.RangeEditField.Value;
            x     = -app.r:0.1:app.r;
            y     = fh(x);
            plot(app.UIAxes,x,y,'r')
        end

        % Button pushed function: PlotIntegralButton
        function PlotIntegralButtonPushed(app, event)
            type   = app.IntegrationTypeDropDown.Value;
            if strcmp(type,'Select...')
                msgbox('Pick a type','Error','error')
                return
            end
            f      = app.FunctionEditField.Value;
            fh     = str2func(f);
            app.le = app.LeftEndpointEditField.Value;
            app.re = app.RightEndpointEditField.Value;
            app.N  = app.SubintervalsEditField.Value;
            I      = calcInt(app,fh,type);
            if ismember(type, ['Left Endpoint','Right Endpoint','Midpoint'])
                pltrec(app,I,type)
            end
            app.DefIntegralEditField.Value = sum(I(:,end));
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.IntegratorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IntegratorUIFigure = uifigure('Visible', 'off');
            app.IntegratorUIFigure.Name = 'Integrator';
            app.IntegratorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.IntegratorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.IntegrationTypeDropDownLabel = uilabel(app.LeftPanel);
            app.IntegrationTypeDropDownLabel.Text = 'Integration Type';
            app.IntegrationTypeDropDown = uidropdown(app.LeftPanel);
            app.IntegrationTypeDropDown.Items = {'Select...', 'Left Endpoint', 'Right Endpoint', 'Midpoint', 'Trapezoidal', 'Simpson'};
            app.IntegrationTypeDropDown.Value = 'Select...';
            app.FunctionEditFieldLabel = uilabel(app.LeftPanel);
            app.FunctionEditFieldLabel.Text = 'Function';
            app.FunctionEditField = uieditfield(app.LeftPanel, 'text');
            app.PlotFunctionButton = uibutton(app.LeftPanel, 'push');
            app.PlotFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFunctionButtonPushed, true);
            app.PlotFunctionButton.Text = 'Plot Function';
            app.PlotIntegralButton = uibutton(app.LeftPanel, 'push');
            app.PlotIntegralButton.ButtonPushedFcn = createCallbackFcn(app, @PlotIntegralButtonPushed, true);
            app.PlotIntegralButton.Text = 'Plot Integral';
            app.RangeEditFieldLabel = uilabel(app.LeftPanel);
            app.RangeEditFieldLabel.Text = 'Range';
            app.RangeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LeftEndpointEditFieldLabel = uilabel(app.LeftPanel);
            app.LeftEndpointEditFieldLabel.Text = 'Left Endpoint';
            app.LeftEndpointEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightEndpointEditFieldLabel = uilabel(app.LeftPanel);
            app.RightEndpointEditFieldLabel.Text = 'Right Endpoint';
            app.RightEndpointEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SubintervalsEditFieldLabel = uilabel(app.LeftPanel);
            app.SubintervalsEditFieldLabel.Text = 'Subintervals';
            app.SubintervalsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DefIntegralEditFieldLabel = uilabel(app.LeftPanel);
            app.DefIntegralEditFieldLabel.Text = 'Def Integral';
            app.DefIntegralEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.YAxisLocation = 'origin';
            % Show the figure after all components are created
            app.IntegratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Integrator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IntegratorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IntegratorUIFigure)
        end
    end
end